classdef GlyphCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateGlyphButton               matlab.ui.control.Button
        GlobalPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Background                      matlab.ui.control.DropDown
        BackgroundDropDownLabel         matlab.ui.control.Label
        BlurStd                         matlab.ui.control.NumericEditField
        BlurstdEditFieldLabel           matlab.ui.control.Label
        WidthGl                         matlab.ui.control.NumericEditField
        WidthEditField_3Label           matlab.ui.control.Label
        HeigthGl                        matlab.ui.control.NumericEditField
        HeigthEditFieldLabel            matlab.ui.control.Label
        Line                            matlab.ui.control.CheckBox
        Spiral                          matlab.ui.control.CheckBox
        JaggedLinePanel                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        LinePeaksHeight                 matlab.ui.control.NumericEditField
        RelativesizeEditField_2Label_2  matlab.ui.control.Label
        LineSize                        matlab.ui.control.NumericEditField
        RelativesizeEditField_2Label    matlab.ui.control.Label
        LineVertShift                   matlab.ui.control.NumericEditField
        VerticalshiftEditField_2Label   matlab.ui.control.Label
        LineHorShift                    matlab.ui.control.NumericEditField
        HorizontalshiftEditField_2Label  matlab.ui.control.Label
        LineDepth                       matlab.ui.control.NumericEditField
        DepthEditField_2Label           matlab.ui.control.Label
        LineLength                      matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        LineWidth                       matlab.ui.control.NumericEditField
        WidthEditField_2Label           matlab.ui.control.Label
        LinePeaks                       matlab.ui.control.NumericEditField
        PeaksEditFieldLabel             matlab.ui.control.Label
        SpiralPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SpiralSize                      matlab.ui.control.NumericEditField
        RelativesizeEditFieldLabel      matlab.ui.control.Label
        SpiralVertShift                 matlab.ui.control.NumericEditField
        VerticalshiftEditFieldLabel     matlab.ui.control.Label
        SpiralHorShift                  matlab.ui.control.NumericEditField
        HorizontalshiftEditFieldLabel   matlab.ui.control.Label
        SpiralDepth                     matlab.ui.control.NumericEditField
        DepthEditFieldLabel             matlab.ui.control.Label
        SpiralTotalWidth                matlab.ui.control.NumericEditField
        DiameterLabel                   matlab.ui.control.Label
        SpiralWidth                     matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        SpiralRounds                    matlab.ui.control.NumericEditField
        RoundsEditFieldLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateGlyphButton
        function CreateGlyphButtonPushed(app, event)
            globalOpt=struct('dimensions',[app.WidthGl.Value,app.HeigthGl.Value],'blurStd',app.BlurStd.Value,'background',app.Background.Value);
            spiral=struct('rounds',app.SpiralRounds.Value,'width',app.SpiralWidth.Value,'totalWidth',app.SpiralTotalWidth.Value,'depth',app.SpiralDepth.Value,'relativePosition',[app.SpiralHorShift.Value,app.SpiralVertShift.Value],'relativeSize',app.SpiralSize.Value);
            line=struct('peaks',app.LinePeaks.Value,'height',app.LinePeaksHeight.Value,'width',app.LineWidth.Value,'length',app.LineLength.Value,'depth',app.LineDepth.Value,'relativePosition',[app.LineHorShift.Value,app.LineVertShift.Value],'relativeSize',app.LineSize.Value);
            options=struct('globalOpt',globalOpt,'spiral',double(app.Spiral.Value),'spiralOpt',spiral,'line',double(app.Line.Value),'lineOpt',line);
            Glyph=createGlyph(options);
            save('Glyph.mat','Glyph')
            imshow(Glyph,[],'parent',app.UIAxes)
            colormap(app.UIAxes, pink);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Glyph')
            app.SpiralPanel = uipanel(app.UIFigure);
            app.SpiralPanel.Title = 'Spiral';
            app.GridLayout2 = uigridlayout(app.SpiralPanel);
            app.RoundsEditFieldLabel = uilabel(app.GridLayout2);
            app.RoundsEditFieldLabel.Text = 'Rounds';
            app.SpiralRounds = uieditfield(app.GridLayout2, 'numeric');
            app.SpiralRounds.Value = 2;
            app.WidthEditFieldLabel = uilabel(app.GridLayout2);
            app.WidthEditFieldLabel.Text = 'Width';
            app.SpiralWidth = uieditfield(app.GridLayout2, 'numeric');
            app.SpiralWidth.Value = 1;
            app.DiameterLabel = uilabel(app.GridLayout2);
            app.DiameterLabel.Text = 'Diameter';
            app.SpiralTotalWidth = uieditfield(app.GridLayout2, 'numeric');
            app.SpiralTotalWidth.Value = 4;
            app.DepthEditFieldLabel = uilabel(app.GridLayout2);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.SpiralDepth = uieditfield(app.GridLayout2, 'numeric');
            app.SpiralDepth.Value = 0.2;
            app.HorizontalshiftEditFieldLabel = uilabel(app.GridLayout2);
            app.HorizontalshiftEditFieldLabel.Text = 'Horizontal shift';
            app.SpiralHorShift = uieditfield(app.GridLayout2, 'numeric');
            app.VerticalshiftEditFieldLabel = uilabel(app.GridLayout2);
            app.VerticalshiftEditFieldLabel.Text = 'Vertical shift';
            app.SpiralVertShift = uieditfield(app.GridLayout2, 'numeric');
            app.RelativesizeEditFieldLabel = uilabel(app.GridLayout2);
            app.RelativesizeEditFieldLabel.Text = 'Relative size';
            app.SpiralSize = uieditfield(app.GridLayout2, 'numeric');
            app.SpiralSize.Value = 0.3;
            app.JaggedLinePanel = uipanel(app.UIFigure);
            app.JaggedLinePanel.Title = 'Jagged Line';
            app.GridLayout = uigridlayout(app.JaggedLinePanel);
            app.PeaksEditFieldLabel = uilabel(app.GridLayout);
            app.PeaksEditFieldLabel.Text = 'Peaks';
            app.LinePeaks = uieditfield(app.GridLayout, 'numeric');
            app.LinePeaks.Value = 6;
            app.WidthEditField_2Label = uilabel(app.GridLayout);
            app.WidthEditField_2Label.Text = 'Width';
            app.LineWidth = uieditfield(app.GridLayout, 'numeric');
            app.LineWidth.Value = 1;
            app.LengthEditFieldLabel = uilabel(app.GridLayout);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LineLength = uieditfield(app.GridLayout, 'numeric');
            app.LineLength.Value = 6;
            app.DepthEditField_2Label = uilabel(app.GridLayout);
            app.DepthEditField_2Label.Text = 'Depth';
            app.LineDepth = uieditfield(app.GridLayout, 'numeric');
            app.LineDepth.Value = 0.2;
            app.HorizontalshiftEditField_2Label = uilabel(app.GridLayout);
            app.HorizontalshiftEditField_2Label.Text = 'Horizontal shift';
            app.LineHorShift = uieditfield(app.GridLayout, 'numeric');
            app.LineHorShift.Value = 300;
            app.VerticalshiftEditField_2Label = uilabel(app.GridLayout);
            app.VerticalshiftEditField_2Label.Text = 'Vertical shift';
            app.LineVertShift = uieditfield(app.GridLayout, 'numeric');
            app.LineVertShift.Value = 200;
            app.RelativesizeEditField_2Label = uilabel(app.GridLayout);
            app.RelativesizeEditField_2Label.Text = 'Relative size';
            app.LineSize = uieditfield(app.GridLayout, 'numeric');
            app.LineSize.Value = 0.2;
            app.RelativesizeEditField_2Label_2 = uilabel(app.GridLayout);
            app.RelativesizeEditField_2Label_2.Text = 'Peaks height';
            app.LinePeaksHeight = uieditfield(app.GridLayout, 'numeric');
            app.LinePeaksHeight.Value = 1;
            app.GlobalPanel = uipanel(app.UIFigure);
            app.GlobalPanel.Title = 'Global';
            app.GridLayout3 = uigridlayout(app.GlobalPanel);
            app.Spiral = uicheckbox(app.GridLayout3);
            app.Spiral.Text = 'Spiral';
            app.Spiral.Value = true;
            app.Line = uicheckbox(app.GridLayout3);
            app.Line.Text = 'Jagged Line';
            app.Line.Value = true;
            app.HeigthEditFieldLabel = uilabel(app.GridLayout3);
            app.HeigthEditFieldLabel.Text = 'Heigth';
            app.HeigthGl = uieditfield(app.GridLayout3, 'numeric');
            app.HeigthGl.Value = 600;
            app.WidthEditField_3Label = uilabel(app.GridLayout3);
            app.WidthEditField_3Label.Text = 'Width';
            app.WidthGl = uieditfield(app.GridLayout3, 'numeric');
            app.WidthGl.Value = 600;
            app.BlurstdEditFieldLabel = uilabel(app.GridLayout3);
            app.BlurstdEditFieldLabel.Text = 'Blur std';
            app.BlurStd = uieditfield(app.GridLayout3, 'numeric');
            app.BlurStd.Value = 1;
            app.BackgroundDropDownLabel = uilabel(app.GridLayout3);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.Background = uidropdown(app.GridLayout3);
            app.Background.Items = {'Option 1', 'Option 2'};
            app.CreateGlyphButton = uibutton(app.UIFigure, 'push');
            app.CreateGlyphButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGlyphButtonPushed, true);
            app.CreateGlyphButton.Text = 'Create Glyph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GlyphCreator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end